classdef shaftDynamicsSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DampingCoefficient0Label      matlab.ui.control.Label
        Avoidtimestep0001and001Label  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        matlabRotation                matlab.ui.control.NumericEditField
        MatlabfullrotationsLabel      matlab.ui.control.Label
        simulinkRotation              matlab.ui.control.NumericEditField
        SimulinkfullrotationsLabel    matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        ShaftSimulationMATLABSimulinkComparisonLabel  matlab.ui.control.Label
        TorquePanel                   matlab.ui.container.Panel
        dampingField                  matlab.ui.control.NumericEditField
        CoulombFrictionTorqueNmEditField_2Label_3  matlab.ui.control.Label
        frictionField                 matlab.ui.control.NumericEditField
        CoulombFrictionTorqueNmEditField_2Label_2  matlab.ui.control.Label
        torqueField                   matlab.ui.control.NumericEditField
        AppliedTorqueNmEditField_2Label  matlab.ui.control.Label
        SimulationOptionsPanel        matlab.ui.container.Panel
        totalTimeField                matlab.ui.control.NumericEditField
        TotalSimulationTimeTsEditFieldLabel  matlab.ui.control.Label
        stepsField                    matlab.ui.control.NumericEditField
        TimeStepts0001sLabel          matlab.ui.control.Label
        angularPositionField          matlab.ui.control.NumericEditField
        InitialAngularPositionradEditFieldLabel  matlab.ui.control.Label
        SimulationControlsPanel       matlab.ui.container.Panel
        AnimateShaftCheckBox          matlab.ui.control.CheckBox
        ClearButton                   matlab.ui.control.Button
        RunSimulationButton           matlab.ui.control.Button
        ShaftPropertiesPanel          matlab.ui.container.Panel
        lengthField                   matlab.ui.control.NumericEditField
        ShaftWidthDiametermLabel_2    matlab.ui.control.Label
        diameterField                 matlab.ui.control.NumericEditField
        ShaftWidthDiametermLabel      matlab.ui.control.Label
        massField                     matlab.ui.control.NumericEditField
        ShaftMasskgLabel              matlab.ui.control.Label
        UIAxesMatlab                  matlab.ui.control.UIAxes
        UIAxesSimulink                matlab.ui.control.UIAxes
        UIAxesAnimation               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
       % Control wether animation is active
       animate = false; % true = on, false = off

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
            % Clear old plots
            cla(app.UIAxesMatlab);
            cla(app.UIAxesSimulink);
            cla(app.UIAxesAnimation);

            % Get user inputs from teh App Designer UI
            params = userinput(app);

            % Run teh rotational simulation function using teh parameters
            results = rotationalFunction(app, params);

            % Run teh Simulink shaft simulator with teh same parameters
            simResults = shaftSimulator(app, params);

            % Check if animation is enabled in teh app
            if app.animate
                % Check that results has a 'theta' (Data) field and it is not empty
                if isfield(results, 'theta') && ~isempty(results.theta)
                    % Safe to use results.theta for animation
                    animateShaftFace(app, params, results, simResults);
                end
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            % Clear old plots
            cla(app.UIAxesMatlab);
            cla(app.UIAxesSimulink);
            cla(app.UIAxesAnimation);
        end

        % Value changed function: AnimateShaftCheckBox
        function AnimateShaftCheckBoxValueChanged(app, event)

            % Check teh state of teh Animate Shaft checkbox in teh App Designer UI
            if app.AnimateShaftCheckBox.Value
                % on = enable animation
                app.animate = true;
            else
                % off = disable animation
                app.animate = false;
            end

        end

        % Button down function: UIAxesAnimation
        function UIAxesAnimationButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesAnimation = uiaxes(app.UIFigure);
            title(app.UIAxesAnimation, 'Animation of shaft face')
            app.UIAxesAnimation.XTick = [];
            app.UIAxesAnimation.XTickLabel = '';
            app.UIAxesAnimation.YTick = [];
            app.UIAxesAnimation.YTickLabel = '';
            app.UIAxesAnimation.ZTick = [];
            app.UIAxesAnimation.ButtonDownFcn = createCallbackFcn(app, @UIAxesAnimationButtonDown, true);
            app.UIAxesSimulink = uiaxes(app.UIFigure);
            title(app.UIAxesSimulink, 'Simulink Simulation w/o Friction')
            xlabel(app.UIAxesSimulink, 'Time (s)')
            ylabel(app.UIAxesSimulink, 'Angle (rad)')
            app.UIAxesMatlab = uiaxes(app.UIFigure);
            title(app.UIAxesMatlab, 'MATLAB Simulation w/ Friction ')
            xlabel(app.UIAxesMatlab, 'Time (s)')
            ylabel(app.UIAxesMatlab, 'Angle(rad)')
            zlabel(app.UIAxesMatlab, 'Z')
            app.ShaftPropertiesPanel = uipanel(app.UIFigure);
            app.ShaftPropertiesPanel.Title = 'Shaft Properties Panel';
            app.ShaftMasskgLabel = uilabel(app.ShaftPropertiesPanel);
            app.ShaftMasskgLabel.Text = 'Shaft Mass (kg)';
            app.massField = uieditfield(app.ShaftPropertiesPanel, 'numeric');
            app.massField.Value = 6;
            app.ShaftWidthDiametermLabel = uilabel(app.ShaftPropertiesPanel);
            app.ShaftWidthDiametermLabel.Text = ' Diameter (m)';
            app.diameterField = uieditfield(app.ShaftPropertiesPanel, 'numeric');
            app.diameterField.Value = 0.05;
            app.ShaftWidthDiametermLabel_2 = uilabel(app.ShaftPropertiesPanel);
            app.ShaftWidthDiametermLabel_2.Text = 'Length (m)';
            app.lengthField = uieditfield(app.ShaftPropertiesPanel, 'numeric');
            app.lengthField.Value = 1;
            app.SimulationControlsPanel = uipanel(app.UIFigure);
            app.SimulationControlsPanel.Title = 'Simulation Controls Panel';
            app.RunSimulationButton = uibutton(app.SimulationControlsPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ClearButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AnimateShaftCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.AnimateShaftCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimateShaftCheckBoxValueChanged, true);
            app.AnimateShaftCheckBox.Text = 'Animate Shaft';
            app.SimulationOptionsPanel = uipanel(app.UIFigure);
            app.SimulationOptionsPanel.Title = 'Simulation Options Panel';
            app.InitialAngularPositionradEditFieldLabel = uilabel(app.SimulationOptionsPanel);
            app.InitialAngularPositionradEditFieldLabel.Text = 'Initial Angular Position θ₀ (rad)';
            app.angularPositionField = uieditfield(app.SimulationOptionsPanel, 'numeric');
            app.TimeStepts0001sLabel = uilabel(app.SimulationOptionsPanel);
            app.TimeStepts0001sLabel.Text = 'Time Step Δt (s) **';
            app.stepsField = uieditfield(app.SimulationOptionsPanel, 'numeric');
            app.stepsField.Value = 0.001;
            app.TotalSimulationTimeTsEditFieldLabel = uilabel(app.SimulationOptionsPanel);
            app.TotalSimulationTimeTsEditFieldLabel.Text = 'Total Simulation Time T (s)';
            app.totalTimeField = uieditfield(app.SimulationOptionsPanel, 'numeric');
            app.totalTimeField.Value = 10;
            app.TorquePanel = uipanel(app.UIFigure);
            app.TorquePanel.Title = 'Torque Panel';
            app.AppliedTorqueNmEditField_2Label = uilabel(app.TorquePanel);
            app.AppliedTorqueNmEditField_2Label.Text = 'Applied Torque (Nm)';
            app.torqueField = uieditfield(app.TorquePanel, 'numeric');
            app.torqueField.Value = 0.0024;
            app.CoulombFrictionTorqueNmEditField_2Label_2 = uilabel(app.TorquePanel);
            app.CoulombFrictionTorqueNmEditField_2Label_2.Text = 'Coulomb Friction Torque (Nm)';
            app.frictionField = uieditfield(app.TorquePanel, 'numeric');
            app.frictionField.Value = 0.001;
            app.CoulombFrictionTorqueNmEditField_2Label_3 = uilabel(app.TorquePanel);
            app.CoulombFrictionTorqueNmEditField_2Label_3.Text = 'Damping Coefficient (Nms/rad)*';
            app.dampingField = uieditfield(app.TorquePanel, 'numeric');
            app.dampingField.Value = 0.0001;
            app.ShaftSimulationMATLABSimulinkComparisonLabel = uilabel(app.UIFigure);
            app.ShaftSimulationMATLABSimulinkComparisonLabel.Text = 'Shaft Simulation: MATLAB & Simulink Comparison';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This project investigates the nonlinear dynamics of a rotational steel shaft under motor-applied torque. It compares MATLAB (with friction) and Simulink simulations (without friction). A custom GUI synchronizes MATLAB and Simulink results in a linked animation for direct visual comparison over time. Validation is achieved by examining peak responses in graphs and through numerical comparison. The study demonstrates how friction influences shaft behavior, providing insight for the design and control of rotational systems.'};
            app.SimulinkfullrotationsLabel = uilabel(app.UIFigure);
            app.SimulinkfullrotationsLabel.Text = 'Simulink full rotations';
            app.simulinkRotation = uieditfield(app.UIFigure, 'numeric');
            app.simulinkRotation.Editable = 'off';
            app.MatlabfullrotationsLabel = uilabel(app.UIFigure);
            app.MatlabfullrotationsLabel.Text = 'Matlab full rotations';
            app.matlabRotation = uieditfield(app.UIFigure, 'numeric');
            app.matlabRotation.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Avoidtimestep0001and001Label = uilabel(app.UIFigure);
            app.Avoidtimestep0001and001Label.Text = '** Avoid time step < 0.001 and > 0.01';
            app.DampingCoefficient0Label = uilabel(app.UIFigure);
            app.DampingCoefficient0Label.Text = '* Damping Coefficient ≠ 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shaftDynamicsSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end